classdef ConfigGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigurationFileEditorGannetGUIV02UIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        AcquisitionParametersPanel     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        EditedmetabolitesofinterestLabel  matlab.ui.control.Label
        OrderofeditingpulsesLabel      matlab.ui.control.Label
        OriginofPhilipsMEGAPRESSGEHERMESsequencesLabel  matlab.ui.control.Label
        AcquisitontypeDropDownLabel    matlab.ui.control.Label
        AcquisitionTypeDropdown        matlab.ui.control.DropDown
        MetabListDropdown              matlab.ui.control.DropDown
        OrderOfEditingPulsesDropdown   matlab.ui.control.DropDown
        OriginOfSequencesDropdown      matlab.ui.control.DropDown
        PhantomAcquisitionCheckBox     matlab.ui.control.CheckBox
        PRIAMAcquisitionCheckBox       matlab.ui.control.CheckBox
        AnalysisParametersPanel        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        LineBroadening                 matlab.ui.control.NumericEditField
        LinebroadeningHzLabel          matlab.ui.control.Label
        AlignmentmethodLabel           matlab.ui.control.Label
        AlignmentMethodDropdown        matlab.ui.control.DropDown
        EddyCorrectionWaterLabel       matlab.ui.control.Label
        EddyCorrectionMetaboliteLabel  matlab.ui.control.Label
        RemoveResidualWaterLabel       matlab.ui.control.Label
        PerformprealignmentLabel       matlab.ui.control.Label
        PreAlignmentDropdown           matlab.ui.control.DropDown
        FitresidualwatersignalintheDIFFspectrumLabel  matlab.ui.control.Label
        AveragesubspectrausingweightedaveragingLabel  matlab.ui.control.Label
        Voxel1nameLabel                matlab.ui.control.Label
        Voxel2namePRIAMdataonlyLabel   matlab.ui.control.Label
        Voxel1Name                     matlab.ui.control.EditField
        Voxel2Name                     matlab.ui.control.EditField
        EddyCorrectionWaterDropdown    matlab.ui.control.DropDown
        EddyCorrectionMetaboliteDropdown  matlab.ui.control.DropDown
        RemoveResidualWaterDropdown    matlab.ui.control.DropDown
        FitResidualWaterDropdown       matlab.ui.control.DropDown
        WeightedAveragingDropdown      matlab.ui.control.DropDown
        AdditionalOptionsPanel         matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SaveMRS_structasamatfileLabel  matlab.ui.control.Label
        SaveprocessedDIFFspectrumasasdatfileonlyforLabel  matlab.ui.control.Label
        ExctractusefuldatafromMRS_structandexporttoacsvfileLabel  matlab.ui.control.Label
        SaveAsMATDropdown              matlab.ui.control.DropDown
        SaveAsSDATDropdown             matlab.ui.control.DropDown
        ExportToCSVDropdown            matlab.ui.control.DropDown
        SaveandContinuePanel           matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ConfigFileName                 matlab.ui.control.EditField
        ConfigurationfilenameexcludematextensionLabel  matlab.ui.control.Label
        SaveAndContinueButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        metabLists = {["GABAGlx" "GSH" "Lac" "EtOH"] ["GABAGlx, GSH" "Lac, GSH" "EtOH, GABA, GSH"] ... % Stores possible values for metabolites of interest grouped by acquisition type
            "GABAGlx, GSH" ["GABA" "Glx" "GSH" "Lac" "EtOH"] ...
            ["GABA, GSH" "Glx, GSH" "Lac, GSH" "EtOH, GABA, GSH"]}
        gannetGUI;     % GUI that called and opened this one
        path;          % Path of passed in file
        name;          % Name of passed in file
    end
    
    methods (Access = private)
        
        % Converts from 'Yes' or 'No' to a logical that can be read by
        % Gannet
        function value = DropdownValue(~, dropdown)
            value = 0;
            if strcmp(dropdown.Value, 'Yes')
                value = 1;
            end
        end
        
        % Converts from a logical value to 'Yes' or 'No'
        function value = FillDropdownValue(~, boolean)
            value = 'No';
            if boolean == 1
                value = 'Yes';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gannetGUI, path, fileName)
            % On startup, initialize the GUI with the default metabList and
            % store information. If the GUI was called with a file, read
            % the data from that file and update the GUI's visual elements
            % accordingly
            
            app.MetabListDropdown.Items = app.metabLists{1};
            app.gannetGUI = gannetGUI;
            app.path = path;
            app.name = fileName;
            app.ConfigFileName.Value = fileName(1:end - 4);
            
            if ~isempty(fileName)
                filePath = fullfile(path, fileName);
                if isfile(filePath)
                    try
                        configFile = load(filePath, 'configFile');
                        configFile = configFile.configFile;
                    catch
                        uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'It looks like the selected .mat file is not a valid configuration file.', 'Invalid File Selected', 'Icon', 'warning')
                    end
                    try
                        app.PRIAMAcquisitionCheckBox.Value = configFile.isPRIAM;
                        if app.PRIAMAcquisitionCheckBox.Value
                            app.Voxel2Name.Enable = true;
                        end
                        app.PhantomAcquisitionCheckBox.Value = configFile.isPhantom;
                        app.AcquisitionTypeDropdown.Value = configFile.acquisitionType;
                        app.MetabListDropdown.Items = configFile.metabList;
                        app.MetabListDropdown.Value = strjoin(configFile.metabolitesOfInterest, ', ');
                        onOrOff = extractBefore(configFile.orderOfEditingPulses, 'first');
                        app.OrderOfEditingPulsesDropdown.Value = strcat(upper(onOrOff(1)), onOrOff(2:end), ' first');
                        app.OriginOfSequencesDropdown.Value = configFile.originOfSequences;
                        app.LineBroadening.Value = configFile.lineBroadening;
                        app.PreAlignmentDropdown.Value = FillDropdownValue(app, configFile.preAlignment);
                        app.EddyCorrectionWaterDropdown.Value = FillDropdownValue(app, configFile.ECCWater);
                        app.EddyCorrectionMetaboliteDropdown.Value = FillDropdownValue(app, configFile.ECCMetab);
                        app.RemoveResidualWaterDropdown.Value = FillDropdownValue(app, configFile.removeResidualWater);
                        app.FitResidualWaterDropdown.Value = FillDropdownValue(app, configFile.fitResidualWater);
                        app.WeightedAveragingDropdown.Value = FillDropdownValue(app, configFile.weightedAveraging);
                        app.Voxel1Name.Value = configFile.voxelNames{1};
                        if length(configFile.voxelNames) == 2
                            app.Voxel2Name.Value = configFile.voxelNames{2};
                        else
                            app.Voxel2Name.Value = 'voxel2';
                        end
                        app.SaveAsMATDropdown.Value = FillDropdownValue(app, configFile.saveAsMAT);
                        app.SaveAsSDATDropdown.Value = FillDropdownValue(app, configFile.saveAsSDAT);
                        app.ExportToCSVDropdown.Value = FillDropdownValue(app, configFile.exportToCSV);
                        
                        num = 1;
                        
                        % Some GUI options are mutually exclusive; check
                        % for them here and update
                        if strcmp(app.AcquisitionTypeDropdown.Value, 'HERCULES')
                            num = 3;
                            app.PhantomAcquisitionCheckBox.Enable = false;
                            app.PhantomAcquisitionCheckBox.Value = 0;
                            app.OrderOfEditingPulsesDropdown.Enable = false;
                        elseif strcmp(app.AcquisitionTypeDropdown.Value, 'HERMES')
                            num = 2;
                            app.OrderOfEditingPulsesDropdown.Enable = false;
                        elseif ~strcmp(app.AcquisitionTypeDropdown.Value, 'MEGA-PRESS')
                            uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'Invalid acquisition type given. Options are MEGA-PRESS, HERMES, or HERCULES.', 'Invalid Acquisition Type', 'Icon', 'info')
                            app.AcquisitionTypeDropdown.Value = 'MEGA-PRESS';
                            app.MetabListDropdown.Items = app.metabLists{1};
                        end
                        
                        if app.PhantomAcquisitionCheckBox.Value
                            correctMetabList = app.metabLists{num + 3};
                            if ~all(strcmp(app.MetabListDropdown.Items, correctMetabList))
                                uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'The given list of metabolites is incorrect for the chosen acquisition type.', 'Incorrect list of metabolites', 'Icon', 'info')
                                app.MetabListDropdown.Items = correctMetabList;
                            end
                        else
                            correctMetabList = app.metabLists{num};
                            if ~all(strcmp(app.MetabListDropdown.Items, correctMetabList))
                                uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'The given list of metabolites is incorrect for the chosen acquisition type.', 'Incorrect list of metabolites', 'Icon', 'info')
                                app.MetabListDropdown.Items = correctMetabList;
                            end
                        end
                    catch
                        uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'One or more fields could not be filled from the given configuration file. Please check that the file''s fields are properly named.', 'Error Loading File', 'Icon', 'warning')
                    end
                else
                    uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, strcat("The file ", filePath, " was not found."), "File Not Found", "Icon", "warning")
                end
            end
        end

        % Value changed function: AcquisitionTypeDropdown
        function AcquisitionTypeDropdownValueChanged(app, event)
            % Change the metabList based on the new acquisition type. Also
            % enable/disable the Phantom and pulse order options based on
            % acquisition type
            for option = 1:length(app.AcquisitionTypeDropdown.Items)
                if strcmp(event.Source.Value, app.AcquisitionTypeDropdown.Items(option))
                    num = option;
                    break
                end
            end
            
            if num == 3
                app.PhantomAcquisitionCheckBox.Enable = false;
                app.PhantomAcquisitionCheckBox.Value = 0;
                app.OrderOfEditingPulsesDropdown.Enable = false;
            else
                if num == 1
                    app.OrderOfEditingPulsesDropdown.Enable = true;
                else
                    app.OrderOfEditingPulsesDropdown.Enable = false;
                end
                
                app.PhantomAcquisitionCheckBox.Enable = true;
            end
            
            if app.PhantomAcquisitionCheckBox.Value
                app.MetabListDropdown.Items = app.metabLists{num + 3};
            else
                app.MetabListDropdown.Items = app.metabLists{num};
            end
        end

        % Value changed function: PhantomAcquisitionCheckBox
        function PhantomAcquisitionCheckBoxValueChanged(app, event)
            % Enabling/Disabling the phantom option affects the possible
            % metabolites of interest
            for option = 1:length(app.AcquisitionTypeDropdown.Items)
                if strcmp(app.AcquisitionTypeDropdown.Value, app.AcquisitionTypeDropdown.Items(option))
                    num = option;
                    break
                end
            end
            
            if app.PhantomAcquisitionCheckBox.Value
                app.AcquisitionTypeDropdown.Items = app.AcquisitionTypeDropdown.Items(1:end - 1);
                app.MetabListDropdown.Items = app.metabLists{num + 3};
            else
                app.AcquisitionTypeDropdown.Items = [app.AcquisitionTypeDropdown.Items "HERCULES"];
                app.MetabListDropdown.Items = app.metabLists{num};
            end
        end

        % Value changed function: PRIAMAcquisitionCheckBox
        function PRIAMAcquisitionCheckBoxValueChanged(app, event)
            % If PRIAM is chosen, 2 voxels need to be named
            if app.PRIAMAcquisitionCheckBox.Value
                app.Voxel2Name.Enable = true;
            else
                app.Voxel2Name.Enable = false;
            end
        end

        % Button pushed function: SaveAndContinueButton
        function SaveAndContinueButtonPushed(app, event)
            % If the file name is not empty, save the data from the GUI
            % into a Matlab structure that can then be loaded and read
            if ~isempty(strip(app.ConfigFileName.Value))
                configFile = struct;
                
                configFile.isPRIAM = app.PRIAMAcquisitionCheckBox.Value;
                configFile.isPhantom = app.PhantomAcquisitionCheckBox.Value;
                configFile.acquisitionType = app.AcquisitionTypeDropdown.Value;
                configFile.metabList = app.MetabListDropdown.Items;
                configFile.metabolitesOfInterest = cellstr(strsplit(app.MetabListDropdown.Value, ', '));
                configFile.orderOfEditingPulses = lower(erase(app.OrderOfEditingPulsesDropdown.Value, ' '));
                configFile.originOfSequences = convertStringsToChars(app.OriginOfSequencesDropdown.Value);
                
                configFile.lineBroadening = app.LineBroadening.Value;
                configFile.alignmentMethod = app.AlignmentMethodDropdown.Value;
                configFile.preAlignment = DropdownValue(app, app.PreAlignmentDropdown);
                configFile.ECCWater = DropdownValue(app, app.EddyCorrectionWaterDropdown);
                configFile.ECCMetab = DropdownValue(app, app.EddyCorrectionMetaboliteDropdown);
                configFile.removeResidualWater = DropdownValue(app, app.RemoveResidualWaterDropdown);
                configFile.fitResidualWater = DropdownValue(app, app.FitResidualWaterDropdown);
                configFile.weightedAveraging = DropdownValue(app, app.WeightedAveragingDropdown);
                
                configFile.voxelNames{1} = app.Voxel1Name.Value;
                if (app.Voxel2Name.Enable == true)
                    configFile.voxelNames{2} = app.Voxel2Name.Value;
                end
                
                configFile.saveAsMAT = DropdownValue(app, app.SaveAsMATDropdown);
                configFile.saveAsSDAT = DropdownValue(app, app.SaveAsSDATDropdown);
                configFile.exportToCSV = DropdownValue(app, app.ExportToCSVDropdown);
                
                configFile.isHERMES = 0;
                configFile.isHERCULES = 0;
                
                if strcmp(app.AcquisitionTypeDropdown.Value, 'HERCULES')
                    configFile.isHERMES = 1;
                    configFile.isHERCULES = 1;
                elseif strcmp(app.AcquisitionTypeDropdown.Value, 'HERMES')
                    configFile.isHERMES = 1;
                end
                
                if ~exist(app.path, 'dir')
                    mkdir(app.path)
                end
                
                configFilePath = strcat(fullfile(app.path, app.ConfigFileName.Value, '.mat'));
                
                if ~strcmp(fullfile(app.path, app.name), configFilePath) && ~isempty(app.name)
                    choice = uiconfirm(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'It looks like you renamed your configuration file. Would you like to save this file as a re-named copy or re-name the original file?', 'Copy or Re-Name File?', 'Options', {'Re-Name File', 'Create a Copy'}, 'Icon', 'info');
                    
                    if strcmp(choice, 'Re-Name File')
                        save(fullfile(app.path, app.name), "configFile")
                        movefile(fullfile(app.path, app.name), strcat(configFilePath, '.mat'))
                    else
                        save(configFilePath, "configFile")
                    end
                    uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'Your configuration file was successfully updated.', 'File Saved', 'Icon', 'success')
                elseif isempty(app.name)
                    save(configFilePath, "configFile");
                    uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'Your configuration file was successfully saved.', 'File Saved', 'Icon', 'success')
                else
                    save(configFilePath, "configFile");
                    uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, 'Your configuration file was successfully updated.', 'File Saved', 'Icon', 'success')
                end
                
                app.gannetGUI.UpdateConfigList;
                
            else
                uialert(app.ConfigurationFileEditorGannetGUIV02UIFigure, "Please enter a valid file name.", "Invalid File Name", "Icon", "warning")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigurationFileEditorGannetGUIV02UIFigure = uifigure('Visible', 'off');
            app.ConfigurationFileEditorGannetGUIV02UIFigure.Name = 'Configuration File Editor | Gannet GUI V0.2';
            app.GridLayout = uigridlayout(app.ConfigurationFileEditorGannetGUIV02UIFigure);
            app.AcquisitionParametersPanel = uipanel(app.GridLayout);
            app.AcquisitionParametersPanel.Title = 'Acquisition Parameters';
            app.GridLayout2 = uigridlayout(app.AcquisitionParametersPanel);
            app.EditedmetabolitesofinterestLabel = uilabel(app.GridLayout2);
            app.EditedmetabolitesofinterestLabel.Text = 'Edited metabolite(s) of interest:';
            app.OrderofeditingpulsesLabel = uilabel(app.GridLayout2);
            app.OrderofeditingpulsesLabel.Text = 'Order of editing pulses:';
            app.OriginofPhilipsMEGAPRESSGEHERMESsequencesLabel = uilabel(app.GridLayout2);
            app.OriginofPhilipsMEGAPRESSGEHERMESsequencesLabel.Text = 'Origin of Philips MEGA-PRESS/GE HERMES sequences:';
            app.AcquisitontypeDropDownLabel = uilabel(app.GridLayout2);
            app.AcquisitontypeDropDownLabel.Text = 'Acquisiton type:';
            app.AcquisitionTypeDropdown = uidropdown(app.GridLayout2);
            app.AcquisitionTypeDropdown.Items = {'MEGA-PRESS', 'HERMES', 'HERCULES'};
            app.AcquisitionTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @AcquisitionTypeDropdownValueChanged, true);
            app.AcquisitionTypeDropdown.Value = 'MEGA-PRESS';
            app.MetabListDropdown = uidropdown(app.GridLayout2);
            app.OrderOfEditingPulsesDropdown = uidropdown(app.GridLayout2);
            app.OrderOfEditingPulsesDropdown.Items = {'Off first', 'On first'};
            app.OrderOfEditingPulsesDropdown.Value = 'Off first';
            app.OriginOfSequencesDropdown = uidropdown(app.GridLayout2);
            app.OriginOfSequencesDropdown.Items = {'JHU', 'Philips', 'Lythgoe', 'Noeske'};
            app.OriginOfSequencesDropdown.Value = 'JHU';
            app.PhantomAcquisitionCheckBox = uicheckbox(app.GridLayout2);
            app.PhantomAcquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhantomAcquisitionCheckBoxValueChanged, true);
            app.PhantomAcquisitionCheckBox.Text = 'Phantom acquisition';
            app.PRIAMAcquisitionCheckBox = uicheckbox(app.GridLayout2);
            app.PRIAMAcquisitionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PRIAMAcquisitionCheckBoxValueChanged, true);
            app.PRIAMAcquisitionCheckBox.Text = 'PRIAM acquisition';
            app.AnalysisParametersPanel = uipanel(app.GridLayout);
            app.AnalysisParametersPanel.Title = 'Analysis Parameters';
            app.GridLayout3 = uigridlayout(app.AnalysisParametersPanel);
            app.LineBroadening = uieditfield(app.GridLayout3, 'numeric');
            app.LineBroadening.Limits = [0 Inf];
            app.LineBroadening.Value = 3;
            app.LinebroadeningHzLabel = uilabel(app.GridLayout3);
            app.LinebroadeningHzLabel.Text = 'Line-broadening (Hz):';
            app.AlignmentmethodLabel = uilabel(app.GridLayout3);
            app.AlignmentmethodLabel.Text = 'Alignment method:';
            app.AlignmentMethodDropdown = uidropdown(app.GridLayout3);
            app.AlignmentMethodDropdown.Items = {'RobustSpecReg', 'SpecReg', 'SpecRegHERMES', 'Cr', 'Cho', 'NAA', 'H2O', 'CrOFF', 'None'};
            app.AlignmentMethodDropdown.Value = 'RobustSpecReg';
            app.EddyCorrectionWaterLabel = uilabel(app.GridLayout3);
            app.EddyCorrectionWaterLabel.Text = 'Perform eddy current correction on water data?';
            app.EddyCorrectionMetaboliteLabel = uilabel(app.GridLayout3);
            app.EddyCorrectionMetaboliteLabel.Text = 'Perform eddy current correction on metabolite data?';
            app.RemoveResidualWaterLabel = uilabel(app.GridLayout3);
            app.RemoveResidualWaterLabel.Text = 'Remove residual water signal using HSVD?';
            app.PerformprealignmentLabel = uilabel(app.GridLayout3);
            app.PerformprealignmentLabel.Text = 'Perform pre-alignment?';
            app.PreAlignmentDropdown = uidropdown(app.GridLayout3);
            app.PreAlignmentDropdown.Items = {'Yes', 'No'};
            app.PreAlignmentDropdown.Value = 'No';
            app.FitresidualwatersignalintheDIFFspectrumLabel = uilabel(app.GridLayout3);
            app.FitresidualwatersignalintheDIFFspectrumLabel.Text = 'Fit residual water signal in the DIFF spectrum?';
            app.AveragesubspectrausingweightedaveragingLabel = uilabel(app.GridLayout3);
            app.AveragesubspectrausingweightedaveragingLabel.Text = 'Average subspectra using weighted averaging?';
            app.Voxel1nameLabel = uilabel(app.GridLayout3);
            app.Voxel1nameLabel.Text = 'Voxel 1 name:';
            app.Voxel2namePRIAMdataonlyLabel = uilabel(app.GridLayout3);
            app.Voxel2namePRIAMdataonlyLabel.Text = 'Voxel 2 name (PRIAM data only):';
            app.Voxel1Name = uieditfield(app.GridLayout3, 'text');
            app.Voxel1Name.Value = 'voxel1';
            app.Voxel2Name = uieditfield(app.GridLayout3, 'text');
            app.Voxel2Name.Enable = 'off';
            app.Voxel2Name.Value = 'voxel2';
            app.EddyCorrectionWaterDropdown = uidropdown(app.GridLayout3);
            app.EddyCorrectionWaterDropdown.Items = {'Yes', 'No'};
            app.EddyCorrectionWaterDropdown.Value = 'Yes';
            app.EddyCorrectionMetaboliteDropdown = uidropdown(app.GridLayout3);
            app.EddyCorrectionMetaboliteDropdown.Items = {'Yes', 'No'};
            app.EddyCorrectionMetaboliteDropdown.Value = 'No';
            app.RemoveResidualWaterDropdown = uidropdown(app.GridLayout3);
            app.RemoveResidualWaterDropdown.Items = {'Yes', 'No'};
            app.RemoveResidualWaterDropdown.Value = 'Yes';
            app.FitResidualWaterDropdown = uidropdown(app.GridLayout3);
            app.FitResidualWaterDropdown.Items = {'Yes', 'No'};
            app.FitResidualWaterDropdown.Value = 'No';
            app.WeightedAveragingDropdown = uidropdown(app.GridLayout3);
            app.WeightedAveragingDropdown.Items = {'Yes', 'No'};
            app.WeightedAveragingDropdown.Value = 'Yes';
            app.AdditionalOptionsPanel = uipanel(app.GridLayout);
            app.AdditionalOptionsPanel.Title = 'Additional Options';
            app.GridLayout4 = uigridlayout(app.AdditionalOptionsPanel);
            app.SaveMRS_structasamatfileLabel = uilabel(app.GridLayout4);
            app.SaveMRS_structasamatfileLabel.Text = 'Save MRS_struct as a .mat file?';
            app.SaveprocessedDIFFspectrumasasdatfileonlyforLabel = uilabel(app.GridLayout4);
            app.SaveprocessedDIFFspectrumasasdatfileonlyforLabel.Text = 'Save processed DIFF spectrum as a .sdat file (only for Philips .sdat MEGA-Press data)?';
            app.ExctractusefuldatafromMRS_structandexporttoacsvfileLabel = uilabel(app.GridLayout4);
            app.ExctractusefuldatafromMRS_structandexporttoacsvfileLabel.Text = 'Exctract useful data from MRS_struct and export to a .csv file?';
            app.SaveAsMATDropdown = uidropdown(app.GridLayout4);
            app.SaveAsMATDropdown.Items = {'Yes', 'No'};
            app.SaveAsMATDropdown.Value = 'No';
            app.SaveAsSDATDropdown = uidropdown(app.GridLayout4);
            app.SaveAsSDATDropdown.Items = {'Yes', 'No'};
            app.SaveAsSDATDropdown.Value = 'No';
            app.ExportToCSVDropdown = uidropdown(app.GridLayout4);
            app.ExportToCSVDropdown.Items = {'Yes', 'No'};
            app.ExportToCSVDropdown.Value = 'No';
            app.SaveandContinuePanel = uipanel(app.GridLayout);
            app.SaveandContinuePanel.Title = 'Save and Continue';
            app.GridLayout5 = uigridlayout(app.SaveandContinuePanel);
            app.ConfigFileName = uieditfield(app.GridLayout5, 'text');
            app.ConfigurationfilenameexcludematextensionLabel = uilabel(app.GridLayout5);
            app.ConfigurationfilenameexcludematextensionLabel.Text = 'Configuration file name (exclude .mat extension):';
            app.SaveAndContinueButton = uibutton(app.GridLayout5, 'push');
            app.SaveAndContinueButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAndContinueButtonPushed, true);
            app.SaveAndContinueButton.Text = 'Save and Continue';
            % Show the figure after all components are created
            app.ConfigurationFileEditorGannetGUIV02UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigurationFileEditorGannetGUIV02UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigurationFileEditorGannetGUIV02UIFigure)
        end
    end
end