classdef GannetGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GannetGUIAlphaV02UIFigure     matlab.ui.Figure
        MainGridLayout                matlab.ui.container.GridLayout
        StudySelectionGridLayout      matlab.ui.container.GridLayout
        GannetLogo                    matlab.ui.control.Image
        NameAndPathPanel              matlab.ui.container.Panel
        NameAndPathGridLayout         matlab.ui.container.GridLayout
        DirectoryNameLabel            matlab.ui.control.Label
        DirectoryPathLabel            matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        SelectStudyDirectoryButton    matlab.ui.control.Button
        HelpAboutButton               matlab.ui.control.Button
        DataSelectionPanel            matlab.ui.container.Panel
        DataAndHeaderGridLayout       matlab.ui.container.GridLayout
        HeaderGridLayout              matlab.ui.container.GridLayout
        MetaboliteFileLabel           matlab.ui.control.Label
        WaterReferenceFileLabel       matlab.ui.control.Label
        AnatomicalImageFileLabel      matlab.ui.control.Label
        ConfigurationFileLabel        matlab.ui.control.Label
        RemoveRowLabel                matlab.ui.control.Label
        DataGridLayout                matlab.ui.container.GridLayout
        BottomGridLayout              matlab.ui.container.GridLayout
        GridLayout                    matlab.ui.container.GridLayout
        CreateNewConfigurationButton  matlab.ui.control.Button
        ResetWorkspaceButton          matlab.ui.control.Button
        GridLayout2                   matlab.ui.container.GridLayout
        ViewEditExistingConfigurationButton  matlab.ui.control.Button
        LoadExistingWorkspaceButton   matlab.ui.control.Button
        GridLayout3                   matlab.ui.container.GridLayout
        SaveCurrentWorkspaceButton    matlab.ui.control.Button
        ImportConfigurationButton     matlab.ui.control.Button
        ContinueButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        mrsStudyStruct       % Large structure storing all MRS-related data from a study directory
        rowObjects           % Structure that stores GUI elements
        configGUI            % GUI that creates/edits configuration files
        subjectSelectionGUI  % GUI where user chooses which subjects to process
    end
    
    methods (Access = private)
        % Deletes user interface elements from the GUI
        function DeleteUIElements(app, row)
            uiElements = ["metab" "ref" "t1" "config" "removeRow"];
            
            for uiElementNum = 1:5
                delete(app.rowObjects.dataRows(row).(uiElements(uiElementNum)));
            end
        end
        
        function ResetGUI(app)
            app.DirectoryNameLabel.Text = "Directory Name:";
            app.DirectoryPathLabel.Text = "Directory Path:";
            app.mrsStudyStruct = struct;
            
            ClearWorkspace(app);
        end
        
        % Adds a new row to the GUI's table and stores it in the
        % appropriate structure
        function AddRow(app)
            app.DataGridLayout.RowHeight = [app.DataGridLayout.RowHeight 40];
            app.rowObjects.addRow.Layout.Row = app.rowObjects.addRow.Layout.Row + 1;
            
            row = app.rowObjects.numRows + 1;
            
            dropdownNames = ["metab" "ref" "t1" "config"];
            dropdownItems = {[app.mrsStudyStruct.uniqueScans.metab] app.mrsStudyStruct.uniqueScans(1).ref app.mrsStudyStruct.uniqueScans(1).t1 [app.mrsStudyStruct.configFiles]};
            
            for dropdownNum = 1:4
                currentDropdown = uidropdown(app.DataGridLayout);
                currentDropdown.Layout.Column = dropdownNum;
                currentDropdown.Layout.Row = row;
                currentDropdown.Items = dropdownItems{dropdownNum};
                currentDropdown.Tag = int2str(row);
                
                if dropdownNum == 1
                    currentDropdown.ValueChangedFcn = createCallbackFcn(app, @MetabDropdownValueChanged, true);
                end
                
                app.rowObjects.dataRows(row).(dropdownNames(dropdownNum)) = currentDropdown;
            end
            
            currentRemoveRowButton = uibutton(app.DataGridLayout);
            currentRemoveRowButton.Layout.Column = 5;
            currentRemoveRowButton.Layout.Row = row;
            currentRemoveRowButton.Tag = int2str(row);
            currentRemoveRowButton.Text = "X";
            currentRemoveRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRow, true);
            
            app.rowObjects.dataRows(row).removeRow = currentRemoveRowButton;
            app.rowObjects.numRows = app.rowObjects.numRows + 1;
            
            AdjustHeaderColumnWidths(app);
            EnableDisableContinue(app);
        end
        
        % Removes a selected row from the GUI's table and shifts rows
        % beneath it upwards
        function RemoveRow(app, event)
            uiElements = ["metab" "ref" "t1" "config" "removeRow"];
            
            rowNum = str2double(event.Source.Tag);
            
            if app.rowObjects.numRows > 1
                DeleteUIElements(app, rowNum);
                
                for row = (rowNum + 1):app.rowObjects.numRows
                    for uiElementNum = 1:5
                        if uiElementNum == 1
                            app.rowObjects.dataRows(row).metab.Tag = int2str(row - 1);
                        end
                        
                        app.rowObjects.dataRows(row).(uiElements(uiElementNum)).Layout.Row = row - 1;
                    end
                    
                    app.rowObjects.dataRows(row).removeRow.Tag = int2str(row - 1);
                end
                
                app.rowObjects.addRow.Layout.Row = app.rowObjects.addRow.Layout.Row - 1;
                
                app.rowObjects.dataRows(rowNum) = [];
                app.rowObjects.numRows = app.rowObjects.numRows - 1;
                
                app.DataGridLayout.RowHeight = app.DataGridLayout.RowHeight(1:end - 1);
                AdjustHeaderColumnWidths(app);
                EnableDisableContinue(app);
            else
                warning("Cannot remove all rows.")
            end
        end
        
        % Given a scan modality and an array of the unique scan sequences
        % found using that modality, checks if each subject has each
        % sequence
        function CheckFileExistence(app, scanType, uniqueArray)
            for subNum = 1:app.mrsStudyStruct.numSubs
                subName = app.mrsStudyStruct.subjects(subNum).subName;
                app.mrsStudyStruct.allScansTable(subNum).subName = subName;
                
                for scanNum = 1:length(uniqueArray)
                    currentScan = uniqueArray(scanNum);
                    possibleSesName = extractBefore(currentScan, '_');
                    
                    if contains(possibleSesName, 'ses-')
                        sesName = possibleSesName;
                    else
                        sesName = '';
                    end
                    
                    if strcmp(scanType, 't1')
                        modality = 'anat';
                    elseif (strcmp(scanType, 'metab') || strcmp(scanType, 'ref'))
                        modality = 'mrs';
                    end
                    
                    scanName = strcat(subName, '_', currentScan);
                    filePath = fullfile(app.mrsStudyStruct.path, subName, sesName, modality, scanName);
                    fieldName = strcat(scanType, 'Scan', int2str(scanNum));
                    
                    app.mrsStudyStruct.key(currentScan) = fieldName;
                    
                    app.mrsStudyStruct.allScansTable(subNum).(fieldName).filePath = filePath;
                    app.mrsStudyStruct.allScansTable(subNum).(fieldName).isFile = isfile(filePath);
                end
            end
        end
        
        % Adjusts the widths of the header columns to accommodate for the
        % appearance/disappearance of the scroll bar in the GUI
        function AdjustHeaderColumnWidths(app)
            position = app.GannetGUIAlphaV02UIFigure.Position;
            height = 2 * floor(1/5 * position(4) - 12) + 1;
            tableHeight = sum(cell2mat(app.DataGridLayout.RowHeight)) + 10 * (length(app.DataGridLayout.RowHeight) + 1);
            
            if tableHeight > height
                app.HeaderGridLayout.ColumnWidth = {'1x' '1x' '1x' '1x' '0.3x' 7};
            else
                app.HeaderGridLayout.ColumnWidth = {'1x' '1x' '1x' '1x' '0.3x'};
            end
        end
        
        % Update the items in the ref and config dropdowns so that they
        % only show items from the same session as the metab dropdown
        function MetabDropdownValueChanged(app, event)
            rowNum = str2double(event.Source.Tag);
            value = event.Source.Value;
            
            for ses = 1:app.mrsStudyStruct.numSessions
                if any(strcmp(app.mrsStudyStruct.uniqueScans(ses).metab, value))
                    break
                end
            end
            
            app.rowObjects.dataRows(rowNum).ref.Items = app.mrsStudyStruct.uniqueScans(ses).ref;
            app.rowObjects.dataRows(rowNum).t1.Items = app.mrsStudyStruct.uniqueScans(ses).t1;
            
            EnableDisableContinue(app);
        end
        
        % Clears visual workspace and resets the data-storing structures of
        % the GUI
        function ClearWorkspace(app)
            try
                for row = 1:app.rowObjects.numRows
                    DeleteUIElements(app, row);
                end
                
                delete(app.rowObjects.addRow);
            catch
            end
            
            app.rowObjects = struct;
            app.rowObjects.dataRows = struct;
            app.rowObjects.numRows = 0;
            EnableDisable(app, false);
            app.ContinueButton.Enable = false;
        end
        
        % If a new study, builds a default table with as many rows as
        % unique metab sequences found. If not, builds a table with the
        % given number of rows
        function BuildWorkspace(app, newStudy, numRows)
            addRowButton = uibutton(app.DataGridLayout, 'push');
            addRowButton.Layout.Row = 1;
            addRowButton.Layout.Column = 1;
            addRowButton.Text = "(Add Row)";
            addRowButton.FontAngle = 'italic';
            addRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRow, true);
            
            app.rowObjects.addRow = addRowButton;
            
            builtRows = 0;
            
            app.mrsStudyStruct.numSessions = length([app.mrsStudyStruct.uniqueScans.sesName]);
            
            if newStudy
                for ses = 1:app.mrsStudyStruct.numSessions
                    for row = 1:length(app.mrsStudyStruct.uniqueScans(ses).metab)
                        AddRow(app);
                        builtRows = builtRows + 1;
                        app.rowObjects.dataRows(builtRows).metab.Value = app.mrsStudyStruct.uniqueScans(ses).metab(row);
                        app.rowObjects.dataRows(builtRows).ref.Items = app.mrsStudyStruct.uniqueScans(ses).ref;
                        app.rowObjects.dataRows(builtRows).t1.Items = app.mrsStudyStruct.uniqueScans(ses).t1;
                    end
                end
            else
                builtRows = numRows;
                for row = 1:numRows
                   AddRow(app);
                end
            end
            
            if builtRows == 0
                warning('No metabolite files found in the given directory.')
            end
        end
        
        % Given a study directory, load its data into the mrsStudyStruct
        % and build the accompanying table
        function LoadWorkspace(app, studyDir)
            ResetGUI(app);
            
            app.mrsStudyStruct.path = studyDir;
            
            app.DirectoryPathLabel.Text = ['Directory Path: ' studyDir];
            pathParts = strsplit(studyDir, filesep);
            app.DirectoryNameLabel.Text = ['Directory Name: ' char(pathParts(end))];
            
            studySubFolders = dir(studyDir);
            studySubFolders = [studySubFolders(1:end).name ""];
            studySubFolders = studySubFolders(1:end - 1);
            
            subFolders = studySubFolders(contains(studySubFolders, 'sub'));
            app.mrsStudyStruct.numSubs = length(subFolders);
            
            app.mrsStudyStruct.subjects = struct;
            app.mrsStudyStruct.sessions = strings;
            app.mrsStudyStruct.anyWithoutSessions = false;
            
            for subNum = 1:app.mrsStudyStruct.numSubs
                subName = subFolders(subNum);
                insideSubFolder = dir(fullfile(studyDir, subName));
                insideSubFolder = insideSubFolder([insideSubFolder(1:end).isdir]);
                insideSubFolder = [insideSubFolder(1:end).name ""];
                insideSubFolder = insideSubFolder(1:end - 1);
                
                sessions = insideSubFolder(contains(insideSubFolder, 'ses'));
                numSessions = length(sessions);
                
                app.mrsStudyStruct.subjects(subNum).subName = subName;
                
                if ~isempty(sessions)
                    app.mrsStudyStruct.subjects(subNum).sessionFolderPresent = true;
                    app.mrsStudyStruct.subjects(subNum).numSessions = numSessions;
                    
                    for ses = 1:numSessions
                        if ~contains(app.mrsStudyStruct.sessions, sessions(ses))
                            app.mrsStudyStruct.sessions(end + 1) = sessions(ses);
                        end
                    end
                else
                    app.mrsStudyStruct.anyWithoutSessions = true;
                    app.mrsStudyStruct.subjects(subNum).sessionFolderPresent = false;
                    app.mrsStudyStruct.subjects(subNum).numSessions = 1;
                end
            end
            
            app.mrsStudyStruct.sessions = app.mrsStudyStruct.sessions(2:end);
            
            configFiles = dir(fullfile(studyDir, 'derivatives', 'gannet', 'config-files', '*.mat'));
            configFileNames = [configFiles(1:end).name ""];
            app.mrsStudyStruct.configFiles = configFileNames(1:end - 1);
            
            app.mrsStudyStruct.allScans = struct;
            app.mrsStudyStruct.allScans.metab = [];
            app.mrsStudyStruct.allScans.ref = [];
            app.mrsStudyStruct.allScans.t1 = [];
            
            for subNum = 1:app.mrsStudyStruct.numSubs
                subName = subFolders(subNum);
                
                if app.mrsStudyStruct.subjects(subNum).sessionFolderPresent
                    currentFolder = fullfile(studyDir, subName, 'ses-*');
                else
                    currentFolder = fullfile(studyDir, subName);
                end
                
                mrsScans = [dir(fullfile(currentFolder, 'mrs', 'sub-*.dat')); ...
                    dir(fullfile(currentFolder, 'mrs', 'sub-*.sdat')); ...
                    dir(fullfile(currentFolder, 'mrs', 'sub-*.7'))];
                mrsScanNames = [mrsScans(1:end).name ""];
                mrsScanNames = mrsScanNames(1:end - 1);
                
                metabScans = mrsScanNames(~contains(mrsScanNames, '-ref'));
                refScans = mrsScanNames(contains(mrsScanNames, '-ref'));
                
                t1Files = dir(fullfile(currentFolder,'anat','sub-*_T1w.nii'));
                t1FileNames = [t1Files(1:end).name ""];
                t1FileNames = t1FileNames(1:end - 1);
                
                app.mrsStudyStruct.subjects(subNum).metab = metabScans;
                app.mrsStudyStruct.subjects(subNum).ref = refScans;
                app.mrsStudyStruct.subjects(subNum).t1 = t1FileNames;
                
                numOfTotalMetabScans = length([app.mrsStudyStruct.allScans.metab]);
                numOfTotalRefScans = length([app.mrsStudyStruct.allScans.ref]);
                numOfTotalT1Scans = length([app.mrsStudyStruct.allScans.t1]);
                
                largest = max([length(metabScans) length(refScans) length(t1FileNames)]);
                
                for index = 1:largest
                    try
                        app.mrsStudyStruct.allScans(numOfTotalMetabScans + index).metab = metabScans(index);
                    catch
                    end
                    try
                        app.mrsStudyStruct.allScans(numOfTotalRefScans + index).ref = refScans(index);
                    catch
                    end
                    try
                        app.mrsStudyStruct.allScans(numOfTotalT1Scans + index).t1 = t1FileNames(index);
                    catch
                    end
                end
            end
            
            app.mrsStudyStruct.uniqueScans = struct;
            
            if ~isempty([app.mrsStudyStruct.allScans.metab])
                allUniqueMetabs = unique(extractAfter([app.mrsStudyStruct.allScans.metab], '_'));
            else
                allUniqueMetabs = "";
            end
            if ~isempty([app.mrsStudyStruct.allScans.ref])
                allUniqueRefs = unique(extractAfter([app.mrsStudyStruct.allScans.ref], '_'));
            else
                allUniqueRefs = "";
            end
            if ~isempty([app.mrsStudyStruct.allScans.t1])
                allUniqueT1s = unique(extractAfter([app.mrsStudyStruct.allScans.t1], '_'));
            else
                allUniqueT1s = "";
            end
            
            if app.mrsStudyStruct.anyWithoutSessions
                app.mrsStudyStruct.uniqueScans(1).sesName = 'noSessionName';
                app.mrsStudyStruct.uniqueScans(1).metab = allUniqueMetabs(~contains(allUniqueMetabs, 'ses-'));
                app.mrsStudyStruct.uniqueScans(1).ref = ["None" allUniqueRefs(~contains(allUniqueRefs, 'ses-'))];
                app.mrsStudyStruct.uniqueScans(1).t1 = ["None" allUniqueT1s(~contains(allUniqueT1s, 'ses-'))];
                
                for ses = 1:length(app.mrsStudyStruct.sessions)
                    sesName = app.mrsStudyStruct.sessions(ses);
                    
                    app.mrsStudyStruct.uniqueScans(ses + 1).sesName = sesName;
                    app.mrsStudyStruct.uniqueScans(ses + 1).metab = allUniqueMetabs(contains(allUniqueMetabs, sesName));
                    app.mrsStudyStruct.uniqueScans(ses + 1).ref = ["None" allUniqueRefs(contains(allUniqueRefs, sesName))];
                    app.mrsStudyStruct.uniqueScans(ses + 1).t1 = ["None" allUniqueT1s(contains(allUniqueT1s, sesName))];
                end
            else
                for ses = 1:length(app.mrsStudyStruct.sessions)
                    sesName = app.mrsStudyStruct.sessions(ses);
                    
                    app.mrsStudyStruct.uniqueScans(ses).sesName = sesName;
                    app.mrsStudyStruct.uniqueScans(ses).metab = allUniqueMetabs(contains(allUniqueMetabs, sesName));
                    app.mrsStudyStruct.uniqueScans(ses).ref = ["None" allUniqueRefs(contains(allUniqueRefs, sesName))];
                    app.mrsStudyStruct.uniqueScans(ses).t1 = ["None" allUniqueT1s(contains(allUniqueT1s, sesName))];
                end
            end
            
            app.mrsStudyStruct.allScansTable = struct;
            app.mrsStudyStruct.key = containers.Map;
            
            CheckFileExistence(app, 'metab', allUniqueMetabs);
            CheckFileExistence(app, 'ref', allUniqueRefs);
            CheckFileExistence(app, 't1', allUniqueT1s);
            
            BuildWorkspace(app, 1, 0);
            
            EnableDisable(app, true);
            EnableDisableContinue(app);
        end
        
        % Toggles the interactivity of GUI buttons that should only be
        % enabled when a study has been selected
        function EnableDisable(app, bool)
            app.ResetWorkspaceButton.Enable = bool;
            app.SaveCurrentWorkspaceButton.Enable = bool;
            app.CreateNewConfigurationButton.Enable = bool;
            app.ViewEditExistingConfigurationButton.Enable = bool;
            app.ImportConfigurationButton.Enable = bool;
        end
        
        % Toggles the interactivity of the 'Continue' button, which should
        % only be enabled if each row in the table has a unique metab scan
        % sequence and a configuration file
        function EnableDisableContinue(app)
            metabDropdowns = [app.rowObjects.dataRows.metab];
            metabValues = [metabDropdowns.Value ""];
            
            numMetabs = length(unique(metabValues(1:end - 1)));
            
            if numMetabs ~= length(metabDropdowns)
                app.ContinueButton.Enable = false;
            elseif ~isempty(app.rowObjects.dataRows(1).config.Items)
                app.ContinueButton.Enable = true;
            end
        end
    end

    methods (Access = public)
        % Updates the list of config files in each config dropdown after
        % the ConfigGUI is closed
        function UpdateConfigList(app)
            configFiles = dir(fullfile(app.mrsStudyStruct.path, 'derivatives', 'gannet', 'config-files', '*.mat'));
            configFileNames = [configFiles(1:end).name ""];
            app.mrsStudyStruct.configFiles = configFileNames(1:end - 1);
            
            for row = 1:length(app.rowObjects.dataRows)
                app.rowObjects.dataRows(row).config.Items = app.mrsStudyStruct.configFiles;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectStudyDirectoryButton
        function SelectStudyDirectoryButtonPushed(app, event)
            studyDir = uigetdir(pwd, 'Select a BIDS-Organized Study Directory');
            
            if studyDir ~= 0
                LoadWorkspace(app, studyDir);
            end
        end

        % Size changed function: GannetGUIAlphaV02UIFigure
        function GannetGUIAlphaV02UIFigureSizeChanged(app, event)
            AdjustHeaderColumnWidths(app);
        end

        % Button pushed function: ResetWorkspaceButton
        function ResetWorkspaceButtonPushed(app, event)
            ClearWorkspace(app);
            BuildWorkspace(app, 1, 0);
            EnableDisable(app, true);
            EnableDisableContinue(app);
        end

        % Button pushed function: CreateNewConfigurationButton
        function CreateNewConfigurationButtonPushed(app, event)
            app.configGUI = ConfigGUI(app, fullfile(app.mrsStudyStruct.path, 'derivatives', 'gannet', 'config-files'), '');
        end

        % Button pushed function: 
        % ViewEditExistingConfigurationButton
        function ViewEditExistingConfigurationButtonPushed(app, event)
            [name, path] = uigetfile('*.mat', 'Select a Configuration File');
            
            if name ~= 0
                app.configGUI = ConfigGUI(app, path, name);
            end
        end

        % Button pushed function: ImportConfigurationButton
        function ImportConfigurationButtonPushed(app, event)
            configFilesFolder = fullfile(app.mrsStudyStruct.path, 'derivatives', 'gannet', 'config-files');
            
            if exist(configFilesFolder, 'dir')
            else
                mkdir(configFilesFolder)
            end
            
            [name, path] = uigetfile('*.mat', 'Select a Configuration File');
            
            if name ~= 0
                if isfile(fullfile(path, name))
                    if isfile(fullfile(configFilesFolder, name))
                        uialert(app.GannetGUIAlphaV02UIFigure, 'There is already a configuration file with this name in the current study. Please re-name one of the files and try again.', 'File Already Exists', 'Icon', 'warning')
                    else
                        try
                            copyfile(fullfile(path, name), configFilesFolder)
                            uialert(app.GannetGUIAlphaV02UIFigure, 'The configuration file was successfully imported into the current study directory.', 'File Imported Successfully', 'Icon', 'success')
                        catch
                            uialert(app.GannetGUIAlphaV02UIFigure, 'Error copying file to the given study. Ensure that write access is enabled for the destination folder.', 'Error Copying File')
                        end
                    end
                end
                
                UpdateConfigList(app);
            end
        end

        % Close request function: GannetGUIAlphaV02UIFigure
        function GannetGUIAlphaV02UIFigureCloseRequest(app, event)
            try
                delete(app.configGUI)
            catch
            end
            delete(app)
        end

        % Button pushed function: HelpAboutButton
        function HelpAboutButtonPushed(app, event)
            uialert(app.GannetGUIAlphaV02UIFigure, ['GUIs built and designed by Noam Amozeg and Matthew Chertok in the lab of Dr. Lamb at the University of Florida.', ...
                ' For help and information, please refer to the provided documentation.' newline, newline, 'For additional help, please contact Noam at: namozeg@ufl.edu'], 'Help/About', 'Icon', 'info' ...
                )
        end

        % Button pushed function: SaveCurrentWorkspaceButton
        function SaveCurrentWorkspaceButtonPushed(app, event)
            name = inputdlg('Save file as (exclude .mat extension):','Save Workspace');
            
            if ~isempty(strip(name))
                if ~strcmp(strip(name), "")
                    workspacesFolder = fullfile(app.mrsStudyStruct.path, 'derivatives', 'gannet', 'workspaces');
                    
                    if ~exist(workspacesFolder, 'dir')
                        mkdir(workspacesFolder);
                    end
                    
                    workspaceInfo = struct;
                    
                    workspaceInfo.path = app.mrsStudyStruct.path;
                    
                    for row = 1:length(app.rowObjects.dataRows)
                        workspaceInfo.rows(row).metabList = app.rowObjects.dataRows(row).metab.Items;
                        workspaceInfo.rows(row).refList = app.rowObjects.dataRows(row).ref.Items;
                        workspaceInfo.rows(row).t1List = app.rowObjects.dataRows(row).t1.Items;
                        workspaceInfo.rows(row).configList = app.rowObjects.dataRows(row).config.Items;
                        workspaceInfo.rows(row).metab = app.rowObjects.dataRows(row).metab.Value;
                        workspaceInfo.rows(row).ref = app.rowObjects.dataRows(row).ref.Value;
                        workspaceInfo.rows(row).t1 = app.rowObjects.dataRows(row).t1.Value;
                        workspaceInfo.rows(row).config = app.rowObjects.dataRows(row).config.Value;
                    end
                    save(string(fullfile(workspacesFolder, name)), "workspaceInfo");
                    uialert(app.GannetGUIAlphaV02UIFigure, 'Your workspace was successfully saved.', 'Workspace Saved', 'Icon', 'success')
                else
                    uialert(app.GannetGUIAlphaV02UIFigure, "Please enter a valid file name.", "Invalid File Name", "Icon", "warning")
                end
            end
        end

        % Button pushed function: LoadExistingWorkspaceButton
        function LoadExistingWorkspaceButtonPushed(app, event)
            [name, path] = uigetfile('*.mat', 'Select a Workspace to Load');
            
            if name ~= 0
                try
                    workspace = load(fullfile(path, name), 'workspaceInfo');
                    
                    LoadWorkspace(app, workspace.workspaceInfo.path);
                    
                    ClearWorkspace(app);
                    numRows = length(workspace.workspaceInfo.rows);
                    BuildWorkspace(app, 0, numRows);
                    
                    for row = 1:numRows
                        app.rowObjects.dataRows(row).metab.Items = workspace.workspaceInfo.rows(row).metabList;
                        app.rowObjects.dataRows(row).ref.Items = workspace.workspaceInfo.rows(row).refList;
                        app.rowObjects.dataRows(row).t1.Items = workspace.workspaceInfo.rows(row).t1List;
                        app.rowObjects.dataRows(row).config.Items = workspace.workspaceInfo.rows(row).configList;
                        app.rowObjects.dataRows(row).metab.Value = workspace.workspaceInfo.rows(row).metab;
                        app.rowObjects.dataRows(row).ref.Value = workspace.workspaceInfo.rows(row).ref;
                        app.rowObjects.dataRows(row).t1.Value = workspace.workspaceInfo.rows(row).t1;
                        app.rowObjects.dataRows(row).config.Value = workspace.workspaceInfo.rows(row).config;
                    end
                catch
                    uialert(app.GannetGUIAlphaV02UIFigure, 'There was an error loading the selected workspace. Plase make sure all fields are properly named.', 'Error Loading Workspace', 'Icon', 'error')
                    ResetGUI(app);
                    EnableDisable(app, false);
                end
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.mrsStudyStruct.toBeProcessed = struct;
            
            for row = 1:length(app.rowObjects.dataRows)
                app.mrsStudyStruct.toBeProcessed(row).metab = app.rowObjects.dataRows(row).metab.Value;
                app.mrsStudyStruct.toBeProcessed(row).ref = app.rowObjects.dataRows(row).ref.Value;
                app.mrsStudyStruct.toBeProcessed(row).t1 = app.rowObjects.dataRows(row).t1.Value;
                app.mrsStudyStruct.toBeProcessed(row).config = app.rowObjects.dataRows(row).config.Value;
            end
            
            app.subjectSelectionGUI = SubjectSelectionGUI(app, app.mrsStudyStruct);
            app.GannetGUIAlphaV02UIFigure.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GannetGUIAlphaV02UIFigure = uifigure('Visible', 'off');
            app.GannetGUIAlphaV02UIFigure.Name = 'Gannet GUI Alpha -  V0.2';
            app.GannetGUIAlphaV02UIFigure.CloseRequestFcn = createCallbackFcn(app, @GannetGUIAlphaV02UIFigureCloseRequest, true);
            app.GannetGUIAlphaV02UIFigure.SizeChangedFcn = createCallbackFcn(app, @GannetGUIAlphaV02UIFigureSizeChanged, true);
            app.MainGridLayout = uigridlayout(app.GannetGUIAlphaV02UIFigure);
            app.StudySelectionGridLayout = uigridlayout(app.MainGridLayout);
            app.GannetLogo = uiimage(app.StudySelectionGridLayout);
            app.GannetLogo.ImageSource = 'Gannet Logo.png';
            app.NameAndPathPanel = uipanel(app.StudySelectionGridLayout);
            app.NameAndPathGridLayout = uigridlayout(app.NameAndPathPanel);
            app.DirectoryNameLabel = uilabel(app.NameAndPathGridLayout);
            app.DirectoryNameLabel.Text = 'Directory Name:';
            app.DirectoryPathLabel = uilabel(app.NameAndPathGridLayout);
            app.DirectoryPathLabel.Text = 'Directory Path:';
            app.GridLayout4 = uigridlayout(app.StudySelectionGridLayout);
            app.SelectStudyDirectoryButton = uibutton(app.GridLayout4, 'push');
            app.SelectStudyDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectStudyDirectoryButtonPushed, true);
            app.SelectStudyDirectoryButton.Text = {'Select Study'; 'Directory'};
            app.HelpAboutButton = uibutton(app.GridLayout4, 'push');
            app.HelpAboutButton.ButtonPushedFcn = createCallbackFcn(app, @HelpAboutButtonPushed, true);
            app.HelpAboutButton.Text = 'Help/About';
            app.DataSelectionPanel = uipanel(app.MainGridLayout);
            app.DataAndHeaderGridLayout = uigridlayout(app.DataSelectionPanel);
            app.HeaderGridLayout = uigridlayout(app.DataAndHeaderGridLayout);
            app.MetaboliteFileLabel = uilabel(app.HeaderGridLayout);
            app.MetaboliteFileLabel.Text = 'Metabolite File';
            app.WaterReferenceFileLabel = uilabel(app.HeaderGridLayout);
            app.WaterReferenceFileLabel.Text = 'Water Reference File';
            app.AnatomicalImageFileLabel = uilabel(app.HeaderGridLayout);
            app.AnatomicalImageFileLabel.Text = 'Anatomical Image File';
            app.ConfigurationFileLabel = uilabel(app.HeaderGridLayout);
            app.ConfigurationFileLabel.Text = 'Configuration File';
            app.RemoveRowLabel = uilabel(app.HeaderGridLayout);
            app.RemoveRowLabel.Text = {'Remove'; 'Row'};
            app.DataGridLayout = uigridlayout(app.DataAndHeaderGridLayout);
            app.BottomGridLayout = uigridlayout(app.MainGridLayout);
            app.GridLayout = uigridlayout(app.BottomGridLayout);
            app.CreateNewConfigurationButton = uibutton(app.GridLayout, 'push');
            app.CreateNewConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewConfigurationButtonPushed, true);
            app.CreateNewConfigurationButton.Enable = 'off';
            app.CreateNewConfigurationButton.Text = {'Create New'; 'Configuration'};
            app.ResetWorkspaceButton = uibutton(app.GridLayout, 'push');
            app.ResetWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetWorkspaceButtonPushed, true);
            app.ResetWorkspaceButton.Enable = 'off';
            app.ResetWorkspaceButton.Text = 'Reset Workspace';
            app.GridLayout2 = uigridlayout(app.BottomGridLayout);
            app.ViewEditExistingConfigurationButton = uibutton(app.GridLayout2, 'push');
            app.ViewEditExistingConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewEditExistingConfigurationButtonPushed, true);
            app.ViewEditExistingConfigurationButton.Enable = 'off';
            app.ViewEditExistingConfigurationButton.Text = {'View/Edit Existing'; 'Configuration'};
            app.LoadExistingWorkspaceButton = uibutton(app.GridLayout2, 'push');
            app.LoadExistingWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingWorkspaceButtonPushed, true);
            app.LoadExistingWorkspaceButton.Text = {'Load Existing'; 'Workspace'};
            app.GridLayout3 = uigridlayout(app.BottomGridLayout);
            app.SaveCurrentWorkspaceButton = uibutton(app.GridLayout3, 'push');
            app.SaveCurrentWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentWorkspaceButtonPushed, true);
            app.SaveCurrentWorkspaceButton.Enable = 'off';
            app.SaveCurrentWorkspaceButton.Text = {'Save Current'; 'Workspace'};
            app.ImportConfigurationButton = uibutton(app.GridLayout3, 'push');
            app.ImportConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportConfigurationButtonPushed, true);
            app.ImportConfigurationButton.Enable = 'off';
            app.ImportConfigurationButton.Text = {'Import'; 'Configuration'};
            app.ContinueButton = uibutton(app.BottomGridLayout, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.GannetGUIAlphaV02UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GannetGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GannetGUIAlphaV02UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GannetGUIAlphaV02UIFigure)
        end
    end
end