classdef SubjectSelectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SubjectandFunctionSelectorGannetGUIV02UIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        RunGannetButton          matlab.ui.control.Button
        StudyDirectoryPathLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mrsStudyStruct;  % Same structure from first GUI
        gannetGUI;       % GUI that called this GUI
        tabs;            % Structure that stores the information held in each tab
    end
    
    methods (Access = private)
        
        % Given a tab number, check which subjects should be built and
        % build the tab with those who should
        function BuildTab(app, tabNum)
            gridLayout = app.tabs(tabNum).gridLayout;
            fileGrid = uigridlayout(gridLayout);
            fileGrid.Layout.Row = 2;
            fileGrid.RowHeight = zeros(1, sum(app.tabs(tabNum).shouldBeBuilt)) + 40;
            fileGrid.ColumnWidth = {8, 14, 8, 100, 210, 210, 210};
            fileGrid.Scrollable = true;
            fileGrid.Padding = [0 0 0 0];
            
            app.tabs(tabNum).fileGrid = fileGrid;
            builtRows = 1;
            
            for subject = 1:length(app.tabs(tabNum).shouldBeBuilt)
                if app.tabs(tabNum).shouldBeBuilt(subject)
                    includedCheckbox = uicheckbox(fileGrid);
                    includedCheckbox.Text = '';
                    includedCheckbox.Layout.Row = builtRows;
                    includedCheckbox.Layout.Column = 2;
                    includedCheckbox.Value = app.tabs(tabNum).included(subject);
                    includedCheckbox.Enable = app.tabs(tabNum).enabled(subject);
                    includedCheckbox.ValueChangedFcn = createCallbackFcn(app, @IncludedExcluded, true);
                    includedCheckbox.Tag = strcat(int2str(tabNum), '-', int2str(subject));
                    
                    subName = uilabel(fileGrid);
                    subName.Text = app.tabs(tabNum).data(subject).subject;
                    subName.HorizontalAlignment = 'center';
                    subName.VerticalAlignment = 'center';
                    subName.Layout.Row = builtRows;
                    subName.Layout.Column = 4;
                    
                    app.tabs(tabNum).uielements(subject).includedCheckbox = includedCheckbox;
                    app.tabs(tabNum).uielements(subject).subNameLabel = subName;
                    
                    fieldNames = {'metab', 'ref', 't1'};
                    
                    for file = 1:3
                        label = uilabel(fileGrid);
                        label.Layout.Row = builtRows;
                        label.Layout.Column = 4 + file;
                        label.Text = app.tabs(tabNum).data(subject).(fieldNames{file}).fileName;
                        label.HorizontalAlignment = 'center';
                        label.VerticalAlignment = 'center';
                        
                        if app.tabs(tabNum).data(subject).(fieldNames{file}).exists
                            label.BackgroundColor = [0.67 0.94 0.71];
                        else
                            label.BackgroundColor = [0.96 0.61 0.46];
                        end
                        
                        app.tabs(tabNum).uielements(subject).(fieldNames{file}) = label;
                    end
                    
                    builtRows = builtRows + 1;
                end
            end
        end
        
        % When a subject's inclusion status is changed, the table may need
        % to be updated based on which subjects should be currently visible
        function UpdateTable(app, tabNum, subject)
            app.tabs(tabNum).uielements(subject).includedCheckbox.Visible = false;
            app.tabs(tabNum).uielements(subject).subNameLabel.Visible = false;
            app.tabs(tabNum).uielements(subject).metab.Visible = false;
            app.tabs(tabNum).uielements(subject).ref.Visible = false;
            app.tabs(tabNum).uielements(subject).t1.Visible = false;
            
            for row = subject + 1:length(app.tabs(tabNum).uielements)
                try
                    rowNum = app.tabs(tabNum).uielements(row).includedCheckbox.Layout.Row - 1;
                    app.tabs(tabNum).uielements(row).includedCheckbox.Layout.Row = rowNum;
                    app.tabs(tabNum).uielements(row).subNameLabel.Layout.Row = rowNum;
                    app.tabs(tabNum).uielements(row).metab.Layout.Row = rowNum;
                    app.tabs(tabNum).uielements(row).ref.Layout.Row = rowNum;
                    app.tabs(tabNum).uielements(row).t1.Layout.Row = rowNum;
                catch
                end
            end
        end
        
        % Clicking on the checkbox toggles the inclusion status of a
        % subject in the tab structure and may change which tabs should be
        % built
        function IncludedExcluded(app, event)
            tag = event.Source.Tag;
            tabNum = str2double(extractBefore(tag, '-'));
            subject = str2double(extractAfter(tag, '-'));
            
            app.tabs(tabNum).included(subject) = ~app.tabs(tabNum).included(subject);
            
            if ~strcmp(app.tabs(tabNum).currentlyShowing.Value, 'All Subjects')
                if strcmp(app.tabs(tabNum).currentlyShowing.Value, 'Included Subjects')
                    app.tabs(tabNum).shouldBeBuilt = [app.tabs(tabNum).included];
                else
                    app.tabs(tabNum).shouldBeBuilt = ~[app.tabs(tabNum).included];
                end
                
                UpdateTable(app, tabNum, subject);
            end
        end
        
        % When the 'Currently Showing:' dropdown is changed, the selected
        % tab should be rebuilt to match the dropdown selection
        function CurrentlyShowing(app, event)
            tabNum = str2double(event.Source.Tag);
            size = app.mrsStudyStruct.numSubs;
            
            if strcmp(event.Source.Value, 'All Subjects')
                app.tabs(tabNum).shouldBeBuilt = ones(1, size);
            elseif strcmp(event.Source.Value, 'Included Subjects')
                app.tabs(tabNum).shouldBeBuilt = [app.tabs(tabNum).included];
            else
                app.tabs(tabNum).shouldBeBuilt = ~[app.tabs(tabNum).included];
            end
            
            ClearTab(app, tabNum);
            BuildTab(app, tabNum);
        end
        
        % By deleting the grid layout that holds UI elements, the UI
        % elements are also deleted
        function ClearTab(app, tabNum)
            delete(app.tabs(tabNum).fileGrid);
        end
        
        % Updates which subjects should be shown and included based on
        % which Gannet functions the user wants
        function ChooseFunctionsChanged(app, event)
            tabNum = str2double(event.Source.Tag);
            
            if any(strcmp(app.tabs(tabNum).chooseFunctions.Value, ["GannetCoRegister" "GannetSegment" "GannetQuantify"]))
                app.tabs(tabNum).included = [app.tabs(tabNum).data(1:end).GannetWithT1];
                app.tabs(tabNum).enabled = [app.tabs(tabNum).data(1:end).GannetWithT1];
            else
                app.tabs(tabNum).included = [app.tabs(tabNum).data(1:end).GannetLoad];
                app.tabs(tabNum).enabled = [app.tabs(tabNum).data(1:end).GannetLoad];
            end
            
            size = app.mrsStudyStruct.numSubs;
            
            if strcmp(app.tabs(tabNum).currentlyShowing.Value, 'All Subjects')
                app.tabs(tabNum).shouldBeBuilt = ones(1, size);
            elseif strcmp(app.tabs(tabNum).currentlyShowing.Value, 'Included Subjects')
                app.tabs(tabNum).shouldBeBuilt = [app.tabs(tabNum).included];
            else
                app.tabs(tabNum).shouldBeBuilt = ~[app.tabs(tabNum).included];
            end
            
            ClearTab(app, tabNum);
            BuildTab(app, tabNum);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gannetGUI, mrsStudyStruct)
            % Given the information from the previous GUI, builds a tab for
            % each metab sequence and shows the expected files for each
            % subject and wether they exist or not
            app.gannetGUI = gannetGUI;
            app.mrsStudyStruct = mrsStudyStruct;
            app.tabs = struct;
            app.StudyDirectoryPathLabel.Text = strcat("Study Directory Path: ", app.mrsStudyStruct.path);
            
            for metab = 1:length(app.mrsStudyStruct.toBeProcessed)
                currentTab = uitab(app.TabGroup);
                currentTab.Title = app.mrsStudyStruct.toBeProcessed(metab).metab;
                gridLayout = uigridlayout(currentTab);
                gridLayout.RowHeight = {40, '6x', '1x'};
                gridLayout.ColumnWidth = {'1x'};
                
                headers = uigridlayout(gridLayout);
                headers.Layout.Row = 1;
                headers.RowHeight = {'1x'};
                headers.Padding = [0 0 0 0];
                headers.ColumnWidth = {50, 100, 210, 210, 210};
                
                headerText = {'Included', 'Subject', 'Metabolite File', 'Reference File', 'T1 File'};
                
                for column = 1:5
                    label = uilabel(headers);
                    label.Layout.Column = column;
                    label.Text = headerText{column};
                    label.FontWeight = 'bold';
                    label.HorizontalAlignment = 'center';
                    label.VerticalAlignment = 'center';
                end
                
                fieldNames = {'metab', 'ref', 't1'};
                
                app.tabs(metab).included = zeros(1, app.mrsStudyStruct.numSubs);
                app.tabs(metab).enabled = zeros(1, app.mrsStudyStruct.numSubs);
                
                for subject = 1:app.mrsStudyStruct.numSubs
                    subName = app.mrsStudyStruct.subjects(subject).subName;
                    
                    app.tabs(metab).data(subject).subject = subName;
                    app.tabs(metab).data(subject).GannetFitRef = false;
                    app.tabs(metab).data(subject).GannetWithT1 = false;
                    
                    for field = 1:3
                        fieldName = fieldNames{field};
                        fileType = app.mrsStudyStruct.toBeProcessed(metab).(fieldName);
                        
                        if ~strcmp(fileType, 'None')
                            fileName = strcat(subName, '_', fileType);
                            columnName = app.mrsStudyStruct.key(fileType);
                            exists = app.mrsStudyStruct.allScansTable(subject).(columnName).isFile;
                            path = app.mrsStudyStruct.allScansTable(subject).(columnName).filePath;
                        else
                            fileName = fileType;
                            exists = false;
                            path = 'None';
                        end
                        
                        if field == 1
                            app.tabs(metab).enabled(subject) = exists;
                            app.tabs(metab).data(subject).GannetLoad = exists;
                            app.tabs(metab).included(subject) = exists;
                        elseif field == 2
                            if app.tabs(metab).data(subject).GannetLoad
                                app.tabs(metab).data(subject).GannetFitRef = exists;
                            end
                        else
                            if app.tabs(metab).data(subject).GannetLoad
                                app.tabs(metab).data(subject).GannetWithT1 = exists;
                            end
                        end
                        
                        app.tabs(metab).data(subject).(fieldName).fileName = fileName;
                        app.tabs(metab).data(subject).(fieldName).path = path;
                        app.tabs(metab).data(subject).(fieldName).exists = exists;
                    end
                end
                
                app.tabs(metab).shouldBeBuilt = ones(1,app.mrsStudyStruct.numSubs);
                
                app.tabs(metab).gridLayout = gridLayout;
                
                BuildTab(app, metab)
                
                bottomLayout = uigridlayout(gridLayout);
                bottomLayout.Layout.Row = 3;
                bottomLayout.ColumnWidth = {'1.5x', '1x', '1x', '1x'};
                bottomLayout.RowHeight = {'1x'};
                bottomLayout.Padding = [0 0 0 0];
                
                chooseFunctionsLabel = uilabel(bottomLayout);
                chooseFunctionsLabel.Text = 'At which step do you want to stop Gannet?';
                chooseFunctionsLabel.Layout.Column = 1;
                
                chooseFunctionsDropdown = uidropdown(bottomLayout, 'Items', {'GannetLoad', 'GannetFit', 'GannetCoRegister', 'GannetSegment', 'GannetQuantify'});
                chooseFunctionsDropdown.Layout.Column = 2;
                chooseFunctionsDropdown.Tag = int2str(metab);
                chooseFunctionsDropdown.ValueChangedFcn = createCallbackFcn(app, @ChooseFunctionsChanged, true);
                
                currentlyShowingLabel = uilabel(bottomLayout);
                currentlyShowingLabel.Text = 'Currently showing:';
                currentlyShowingLabel.Layout.Column = 3;
                
                currentlyShowingDropdown = uidropdown(bottomLayout);
                currentlyShowingDropdown.Layout.Column = 4;
                currentlyShowingDropdown.Items = {'All Subjects', 'Included Subjects', 'Excluded Subjects'};
                currentlyShowingDropdown.ValueChangedFcn = createCallbackFcn(app, @CurrentlyShowing, true);
                currentlyShowingDropdown.Tag = int2str(metab);
                
                app.tabs(metab).currentlyShowing = currentlyShowingDropdown;
                app.tabs(metab).chooseFunctions = chooseFunctionsDropdown;
            end
            
            delete(app.Tab);
        end

        % Button pushed function: RunGannetButton
        function RunGannetButtonPushed(app, event)
            % When the button is pushed, the selected Gannet functions
            % should be run on the selected subjects. Note that running
            % GannetFit requires either all included subjects to have ref
            % files or no included subjects to have ref files
            choice = uiconfirm(app.SubjectandFunctionSelectorGannetGUIV02UIFigure, 'Continuing runs Gannet for all tabs currently open. Make sure evrything is formatted as nedded before continuing.', 'Continue?', 'Options', {'Cancel', 'Continue'});
            
            if strcmp(choice, 'Continue')
                uialert(app.SubjectandFunctionSelectorGannetGUIV02UIFigure, 'Gannet is currently running. Please do not close this window.', 'Running Gannet', 'Icon', 'info')
                for tab = 1:length(app.tabs)
                    currentConfigPath = fullfile(app.mrsStudyStruct.path, 'derivatives', 'gannet', 'config-files', app.mrsStudyStruct.toBeProcessed(tab).config);
                    included = app.tabs(tab).included;
                    
                    metabStructure = [app.tabs(tab).data(1:end).metab];
                    metabFilePaths = [metabStructure.path];
                    metabFilePaths = metabFilePaths(included == 1);
                    metabFilePaths = cellstr(metabFilePaths);
                    
                    refStructure = [app.tabs(tab).data(1:end).ref];
                    refFilePaths = [refStructure.path];
                    refFilePaths = refFilePaths(included == 1);
                    refFilePaths = cellstr(refFilePaths);
                    
                    t1Structure = [app.tabs(tab).data(1:end).t1];
                    t1FilePaths = [t1Structure.path];
                    t1FilePaths = t1FilePaths(included == 1);
                    t1FilePaths = cellstr(t1FilePaths);
                    
                    if strcmp(app.tabs(tab).chooseFunctions.Value, 'GannetLoad')
                        MRS = GannetLoad(1, currentConfigPath, metabFilePaths);
                    else
                        refExists = [app.tabs(tab).data.GannetFitRef];
                        includedWithRefs = included & refExists;
                        
                        if (~all(includedWithRefs == included) && ~all(includedWithRefs == zeros(1, length(allHaveRefs))))
                            uialert(app.SubjectandFunctionSelectorGannetGUIV02UIFigure, strcat("Some included subjects were missing water reference files while others had water reference files. Running GannetLoad without any reference files for tab number ", int2str(tab), "."), 'Missing Water Reference Files', 'Icon', 'warning')
                            MRS = GannetLoad(1, currentConfigPath, metabFilePaths);
                        else
                            MRS = GannetLoad(1, currentConfigPath, metabFilePaths, refFilePaths);
                        end
                        
                        MRS = GannetFit(MRS);
                        
                        if strcmp(app.tabs(tab).chooseFunctions.Value, 'GannetCoRegister')
                            MRS = GannetCoRegister(MRS, t1FilePaths);
                            if strcmp(app.tabs(tab).chooseFunctions.Value, 'GannetSegment')
                                MRS = GannetSegment(MRS);
                                if strcmp(app.tabs(tab).chooseFunctions.Value, 'GannetQuantify')
                                    MRS = GannetQuantify(MRS);
                                end
                            end
                        end
                    end
                end
                uialert(app.SubjectandFunctionSelectorGannetGUIV02UIFigure, 'Finished running Gannet successfully. You may now close the GUI.', 'Success', 'Icon', 'success')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SubjectandFunctionSelectorGannetGUIV02UIFigure = uifigure('Visible', 'off');
            app.SubjectandFunctionSelectorGannetGUIV02UIFigure.Name = 'Subject and Function Selector | Gannet GUI V0.2';
            app.GridLayout = uigridlayout(app.SubjectandFunctionSelectorGannetGUIV02UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.RunGannetButton = uibutton(app.GridLayout2, 'push');
            app.RunGannetButton.ButtonPushedFcn = createCallbackFcn(app, @RunGannetButtonPushed, true);
            app.RunGannetButton.Text = 'Run Gannet';
            app.StudyDirectoryPathLabel = uilabel(app.GridLayout2);
            app.StudyDirectoryPathLabel.Text = 'Study Directory Path:';
            % Show the figure after all components are created
            app.SubjectandFunctionSelectorGannetGUIV02UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SubjectSelectionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SubjectandFunctionSelectorGannetGUIV02UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SubjectandFunctionSelectorGannetGUIV02UIFigure)
        end
    end
end